classdef CVAENumGen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        lv01SliderLabel          matlab.ui.control.Label
        lv01                     matlab.ui.control.Slider
        lv02SliderLabel          matlab.ui.control.Label
        lv02                     matlab.ui.control.Slider
        lv06SliderLabel          matlab.ui.control.Label
        lv06                     matlab.ui.control.Slider
        lv07SliderLabel          matlab.ui.control.Label
        lv07                     matlab.ui.control.Slider
        lv11SliderLabel          matlab.ui.control.Label
        lv11                     matlab.ui.control.Slider
        lv12SliderLabel          matlab.ui.control.Label
        lv12                     matlab.ui.control.Slider
        lv16SliderLabel          matlab.ui.control.Label
        lv16                     matlab.ui.control.Slider
        lv17SliderLabel          matlab.ui.control.Label
        lv17                     matlab.ui.control.Slider
        GeneratenewimagesButton  matlab.ui.control.Button
        lv03SliderLabel          matlab.ui.control.Label
        lv03                     matlab.ui.control.Slider
        lv04SliderLabel          matlab.ui.control.Label
        lv04                     matlab.ui.control.Slider
        lv08SliderLabel          matlab.ui.control.Label
        lv08                     matlab.ui.control.Slider
        lv09SliderLabel          matlab.ui.control.Label
        lv09                     matlab.ui.control.Slider
        lv13SliderLabel          matlab.ui.control.Label
        lv13                     matlab.ui.control.Slider
        lv14SliderLabel          matlab.ui.control.Label
        lv14                     matlab.ui.control.Slider
        lv18SliderLabel          matlab.ui.control.Label
        lv18                     matlab.ui.control.Slider
        lv19SliderLabel          matlab.ui.control.Label
        lv19                     matlab.ui.control.Slider
        lv05SliderLabel          matlab.ui.control.Label
        lv05                     matlab.ui.control.Slider
        lv10SliderLabel          matlab.ui.control.Label
        lv10                     matlab.ui.control.Slider
        lv15SliderLabel          matlab.ui.control.Label
        lv15                     matlab.ui.control.Slider
        lv20SliderLabel          matlab.ui.control.Label
        lv20                     matlab.ui.control.Slider
    end

    
    properties (Access = private)
        decoderNet % decoder
        latentVec; % latent variables
    end
    
    methods (Access = private)
        
        function generateImage(app)
            labelarray = dlarray(single(reshape(full(ind2vec(double([1:10]), 10)), [1 1 10 10])), 'SSCB');
            generationSeed = cat(3, app.latentVec, labelarray);
            generatedImage = sigmoid(predict(app.decoderNet, generationSeed));
            generatedImage = extractdata(generatedImage);
            
            imshow(imtile(generatedImage, "ThumbnailSize", [100,100], "GridSize", [1 10]), [], 'Parent', app.UIAxes);        
        end
        
        function generateLatentVec(app)
            latentVec = zeros(1,1,20,1);
            for i = 1:20
                latentVec(i) = app.(sprintf('lv%02d',i)).Value;
            end
            latentVec = dlarray(repmat(latentVec, [1 1 1 10]), 'SSCB');
            app.latentVec = latentVec;
        end
        
        function generateRandomizedLatentVec(app)
            for i = 1:20
                app.(sprintf('lv%02d',i)).Value = rand(1)*10 - 5;
            end
            generateLatentVec(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data = load('decoderNet.mat');
            app.decoderNet = data.decoderNet;
            
            axis(app.UIAxes, 'image');
            
            for i = 1:20
                app.(sprintf('lv%02d', i)).Limits = [-10 10];
            end
        end

        % Button pushed function: GeneratenewimagesButton
        function GeneratenewimagesButtonPushed(app, event)
            generateRandomizedLatentVec(app);
            generateImage(app);
        end

        % Value changing function: lv01
        function lv01ValueChanging(app, event)
            changingValue = event.Value;
            app.lv01.Value = changingValue;
            generateLatentVec(app);
            generateImage(app);
        end

        % Value changed function: lv01
        function lv01ValueChanged(app, event)
            value = app.lv01.Value;
            generateLatentVec(app);
            generateImage(app);            
        end

        % Value changed function: lv02
        function lv02ValueChanged(app, event)
            value = app.lv02.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv07
        function lv07ValueChanged(app, event)
            value = app.lv07.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv11
        function lv11ValueChanged(app, event)
            value = app.lv11.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv12
        function lv12ValueChanged(app, event)
            value = app.lv12.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv16
        function lv16ValueChanged(app, event)
            value = app.lv16.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv17
        function lv17ValueChanged(app, event)
            value = app.lv17.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv03
        function lv03ValueChanged(app, event)
            value = app.lv03.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv04
        function lv04ValueChanged(app, event)
            value = app.lv04.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv08
        function lv08ValueChanged(app, event)
            value = app.lv08.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv09
        function lv09ValueChanged(app, event)
            value = app.lv09.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv13
        function lv13ValueChanged(app, event)
            value = app.lv13.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv14
        function lv14ValueChanged(app, event)
            value = app.lv14.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv18
        function lv18ValueChanged(app, event)
            value = app.lv18.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv19
        function lv19ValueChanged(app, event)
            value = app.lv19.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv05
        function lv05ValueChanged(app, event)
            value = app.lv05.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv10
        function lv10ValueChanged(app, event)
            value = app.lv10.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv15
        function lv15ValueChanged(app, event)
            value = app.lv15.Value;
            generateLatentVec(app);
            generateImage(app);          
        end

        % Value changed function: lv20
        function lv20ValueChanged(app, event)
            value = app.lv20.Value;
            generateLatentVec(app);
            generateImage(app);          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.lv01SliderLabel = uilabel(app.UIFigure);
            app.lv01SliderLabel.Text = 'lv01';
            app.lv01 = uislider(app.UIFigure);
            app.lv01.ValueChangedFcn = createCallbackFcn(app, @lv01ValueChanged, true);
            app.lv01.ValueChangingFcn = createCallbackFcn(app, @lv01ValueChanging, true);
            app.lv02SliderLabel = uilabel(app.UIFigure);
            app.lv02SliderLabel.Text = 'lv02';
            app.lv02 = uislider(app.UIFigure);
            app.lv02.ValueChangedFcn = createCallbackFcn(app, @lv02ValueChanged, true);
            app.lv06SliderLabel = uilabel(app.UIFigure);
            app.lv06SliderLabel.Text = 'lv06';
            app.lv06 = uislider(app.UIFigure);
            app.lv07SliderLabel = uilabel(app.UIFigure);
            app.lv07SliderLabel.Text = 'lv07';
            app.lv07 = uislider(app.UIFigure);
            app.lv07.ValueChangedFcn = createCallbackFcn(app, @lv07ValueChanged, true);
            app.lv11SliderLabel = uilabel(app.UIFigure);
            app.lv11SliderLabel.Text = 'lv11';
            app.lv11 = uislider(app.UIFigure);
            app.lv11.ValueChangedFcn = createCallbackFcn(app, @lv11ValueChanged, true);
            app.lv12SliderLabel = uilabel(app.UIFigure);
            app.lv12SliderLabel.Text = 'lv12';
            app.lv12 = uislider(app.UIFigure);
            app.lv12.ValueChangedFcn = createCallbackFcn(app, @lv12ValueChanged, true);
            app.lv16SliderLabel = uilabel(app.UIFigure);
            app.lv16SliderLabel.Text = 'lv16';
            app.lv16 = uislider(app.UIFigure);
            app.lv16.ValueChangedFcn = createCallbackFcn(app, @lv16ValueChanged, true);
            app.lv17SliderLabel = uilabel(app.UIFigure);
            app.lv17SliderLabel.Text = 'lv17';
            app.lv17 = uislider(app.UIFigure);
            app.lv17.ValueChangedFcn = createCallbackFcn(app, @lv17ValueChanged, true);
            app.GeneratenewimagesButton = uibutton(app.UIFigure, 'push');
            app.GeneratenewimagesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratenewimagesButtonPushed, true);
            app.GeneratenewimagesButton.Text = 'Generate new images';
            app.lv03SliderLabel = uilabel(app.UIFigure);
            app.lv03SliderLabel.Text = 'lv03';
            app.lv03 = uislider(app.UIFigure);
            app.lv03.ValueChangedFcn = createCallbackFcn(app, @lv03ValueChanged, true);
            app.lv04SliderLabel = uilabel(app.UIFigure);
            app.lv04SliderLabel.Text = 'lv04';
            app.lv04 = uislider(app.UIFigure);
            app.lv04.ValueChangedFcn = createCallbackFcn(app, @lv04ValueChanged, true);
            app.lv08SliderLabel = uilabel(app.UIFigure);
            app.lv08SliderLabel.Text = 'lv08';
            app.lv08 = uislider(app.UIFigure);
            app.lv08.ValueChangedFcn = createCallbackFcn(app, @lv08ValueChanged, true);
            app.lv09SliderLabel = uilabel(app.UIFigure);
            app.lv09SliderLabel.Text = 'lv09';
            app.lv09 = uislider(app.UIFigure);
            app.lv09.ValueChangedFcn = createCallbackFcn(app, @lv09ValueChanged, true);
            app.lv13SliderLabel = uilabel(app.UIFigure);
            app.lv13SliderLabel.Text = 'lv13';
            app.lv13 = uislider(app.UIFigure);
            app.lv13.ValueChangedFcn = createCallbackFcn(app, @lv13ValueChanged, true);
            app.lv14SliderLabel = uilabel(app.UIFigure);
            app.lv14SliderLabel.Text = 'lv14';
            app.lv14 = uislider(app.UIFigure);
            app.lv14.ValueChangedFcn = createCallbackFcn(app, @lv14ValueChanged, true);
            app.lv18SliderLabel = uilabel(app.UIFigure);
            app.lv18SliderLabel.Text = 'lv18';
            app.lv18 = uislider(app.UIFigure);
            app.lv18.ValueChangedFcn = createCallbackFcn(app, @lv18ValueChanged, true);
            app.lv19SliderLabel = uilabel(app.UIFigure);
            app.lv19SliderLabel.Text = 'lv19';
            app.lv19 = uislider(app.UIFigure);
            app.lv19.ValueChangedFcn = createCallbackFcn(app, @lv19ValueChanged, true);
            app.lv05SliderLabel = uilabel(app.UIFigure);
            app.lv05SliderLabel.Text = 'lv05';
            app.lv05 = uislider(app.UIFigure);
            app.lv05.ValueChangedFcn = createCallbackFcn(app, @lv05ValueChanged, true);
            app.lv10SliderLabel = uilabel(app.UIFigure);
            app.lv10SliderLabel.Text = 'lv10';
            app.lv10 = uislider(app.UIFigure);
            app.lv10.ValueChangedFcn = createCallbackFcn(app, @lv10ValueChanged, true);
            app.lv15SliderLabel = uilabel(app.UIFigure);
            app.lv15SliderLabel.Text = 'lv15';
            app.lv15 = uislider(app.UIFigure);
            app.lv15.ValueChangedFcn = createCallbackFcn(app, @lv15ValueChanged, true);
            app.lv20SliderLabel = uilabel(app.UIFigure);
            app.lv20SliderLabel.Text = 'lv20';
            app.lv20 = uislider(app.UIFigure);
            app.lv20.ValueChangedFcn = createCallbackFcn(app, @lv20ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVAENumGen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end